classdef RD3_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PRANASPortal                    matlab.ui.Figure
        Settings                        matlab.ui.container.Menu
        Forms                           matlab.ui.container.Menu
        ConsentForm                     matlab.ui.container.Menu
        SurveyForm                      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisType                    matlab.ui.container.Tab
        trialRequestToggle              matlab.ui.control.CheckBox
        TrialNo                         matlab.ui.control.Spinner
        ParticipantUIDEditFieldLabel_3  matlab.ui.control.Label
        ModeSector                      matlab.ui.container.ButtonGroup
        CombinedButton                  matlab.ui.control.RadioButton
        StaticButton                    matlab.ui.control.RadioButton
        BreathButton                    matlab.ui.control.RadioButton
        ParticipantUIDEditFieldLabel_2  matlab.ui.control.Label
        ParticipantUIDPMEditField       matlab.ui.control.EditField
        ParticipantUIDPMLabel           matlab.ui.control.Label
        ConfigurationLabel              matlab.ui.control.Label
        LabTab                          matlab.ui.container.Tab
        trialRequestToggle1             matlab.ui.control.CheckBox
        TrialNo_2                       matlab.ui.control.Spinner
        ParticipantUIDEditFieldLabel_8  matlab.ui.control.Label
        ModeSector_2                    matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        StaticButton_2                  matlab.ui.control.RadioButton
        BreathEmulateButton             matlab.ui.control.RadioButton
        ParticipantUIDEditFieldLabel_7  matlab.ui.control.Label
        BacType                         matlab.ui.control.EditField
        BacteriaTypeBEditFieldLabel     matlab.ui.control.Label
        ConfigurationLabel_2            matlab.ui.control.Label
        VentilatorTab                   matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        Panel_7                         matlab.ui.container.Panel
        NextTrial                       matlab.ui.control.Label
        CurrentTrial                    matlab.ui.control.Label
        NextMode                        matlab.ui.control.Label
        ModeLabel                       matlab.ui.control.Label
        NextLabel                       matlab.ui.control.Label
        CurrentMode                     matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        SetDurationTimer                matlab.ui.control.Label
        SetDurationLabel                matlab.ui.control.Label
        CurrentDurationLabel            matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        UserDropDown                    matlab.ui.control.DropDown
        UserDropDownLabel               matlab.ui.control.Label
        SaveFileNameLabel               matlab.ui.control.Label
        ParticipantUIDEditFieldLabel_6  matlab.ui.control.Label
        ChangeButton                    matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        SaveLabel                       matlab.ui.control.Label
        ParticipantUIDEditFieldLabel_4  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        HelpLabel                       matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_3  matlab.ui.control.Label
        DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_2  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DeviceResponse                  matlab.ui.control.TextArea
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        WaitingforDevicetoConnectLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        DataGroup                       matlab.ui.container.TabGroup
        RATab                           matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        QI1                             matlab.ui.control.Lamp
        QI2                             matlab.ui.control.Lamp
        QI3                             matlab.ui.control.Lamp
        RespiratoryAnalysisLabel        matlab.ui.control.Label
        RRLabel_2                       matlab.ui.control.Label
        Q_Value                         matlab.ui.control.Label
        QLabel                          matlab.ui.control.Label
        LC_Value                        matlab.ui.control.Label
        LCLabel                         matlab.ui.control.Label
        RR_Value                        matlab.ui.control.Label
        RespiratoryQualityIndexLabel    matlab.ui.control.Label
        PowerTimeSeriesPlot             matlab.ui.control.UIAxes
        AGTab                           matlab.ui.container.Tab
        Power1TimeSeriesPlot            matlab.ui.control.UIAxes
        YTimeSeriesPlot                 matlab.ui.control.UIAxes
        XTimeSeriesPlot                 matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        IP = '192.168.0.101 '% Device IP Address - Default is set
        Port ='9001' % Device Port Address
        Client %Device Client
        RecordDuration %Total Record Duration for Mode - 1
        SamplingRate = '1000' % Sampling Rate common across all modes
        SamplingBuffer = '1000' % Sample Buffer common across all modes
        recordedData  % Variable holder for data recording
        logfileData
        expData
        filePathAndName % Description
        loadFilePathAndName
        masterData % Description
        configData % Description
        actSampRate % Description
        UID % Description
        Mode % Description
        Trialno % Description
        UserName
        ExpDuration
        % Flags here
        stopFlag % Description
        trialRequestFlag
        trialRequestFlag1
        ContinueFlag
        

        RunSequence
        trlPtr
        CompleteFlag
        DeviceSessionFlag % Description
        Property27 % Description
        currentPath % Description
        curFolderPath % Description
        curDataFileName % Description
        curLogFileName
        analyType % Description
        currentTrialNo % Description
        mOde % Description
        

        FID_File % Stored IP
        FID_Text
        IP_Addr
        Port_Addr
        T1_Time
        T2_Time
        T3_Time
        T4_Time
        CurEndTime % Description
    end


    methods (Access = private)
        
        function stringData = ComsDataRead(app)
            pause(0.4)
            stringData=char(read(app.Client,app.Client.NumBytesAvailable));
        end
       
        function ComsDataWrite(app,sendString)
            write(app.Client,sendString);
        end

        function DisplayComsData(app,writeString)
            app.DeviceResponse.Value=[app.DeviceResponse.Value;writeString];
        end

        function ManageFilePaths(app)
            if ~exist(strcat(app.currentPath,'\RecordedData'),'dir')
                mkdir(app.currentPath,'RecordedData');
            end
            recFolderPath=strcat(app.currentPath,'\RecordedData');
            if app.analyType=="Clinical"
                curFolderName=strcat('UID_PM-',app.UID);
            elseif app.analyType=="Lab"
                curFolderName=strcat('Lab_B-',app.UID);
            end

            if ~exist(strcat(recFolderPath,'\curFolderName'),'dir')
                mkdir(recFolderPath,curFolderName);
                curFolderPath=strcat(recFolderPath,'\',curFolderName);
            end
            app.curFolderPath=curFolderPath;
            app.SaveLabel.Text=curFolderPath;
        end
        

        function PlotGraphs(app)
            curCSVFile=strcat(app.curFolderPath,"\",app.curDataFileName);
            masterData=readtable(curCSVFile);
            if(app.analyType=="Clinical" )
                if((app.currentTrialNo==1||app.currentTrialNo==3) && app.mOde=="Breath")
                [Resp_Rate, LungCapacity, Quality, FinalQI, mvAvgPow,mvmax,imvmax,mvmin,imvmin]=FinalizedBreathingIndex(curCSVFile,750,app.CurEndTime);
                app.RR_Value.Text=num2str(Resp_Rate);
                app.LC_Value.Text=num2str(round(LungCapacity));
                app.Q_Value.Text=num2str(round(Quality));
                plot(app.PowerTimeSeriesPlot,(1:length(mvAvgPow))/1000,mvAvgPow,'-k',imvmax/1000,mvmax,'or');
%                 plot(app.PowerTimeSeriesPlot,imvmin/1000,mvmin,'ob');hold on; label('all','','','Pow'); grid on; grid minor
                    if FinalQI == 1
                        app.QI1.Enable=true;
                        app.QI2.Enable=false;
                        app.QI3.Enable=false;
                    elseif FinalQI==2
                            app.QI1.Enable=false;
                            app.QI2.Enable=true;
                            app.QI3.Enable=false;
                    else
                         app.QI1.Enable=false;
                            app.QI2.Enable=false;
                            app.QI3.Enable=true;
                    end
                end
%                plot(app.PowerTimeSeriesPlot,masterData.Samples*1000,masterData.Pow);
            elseif(app.analyType=="Lab")
                plot(app.XTimeSeriesPlot,masterData.Samples*1000,masterData.Xpos);
                plot(app.YTimeSeriesPlot,masterData.Samples*1000,masterData.Ypos);
                plot(app.Power1TimeSeriesPlot,masterData.Samples*1000,masterData.Pow);
            end
               
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            Speak(obj, "Experiment Complete");

        end
 
        
        function WaitForDeviceConnection(app)

            app.Client=tcpserver(app.IP_Addr,app.Port_Addr);
            while ~app.Client.Connected
                pause(0.1);
            end
            ComsDataWrite(app,'ServerHere!');
            pause(0.2);
            rcvdData=ComsDataRead(app);
            disp(rcvdData)
            DisplayComsData(app,rcvdData);
            if rcvdData == "Connection Acknowledgement"
                app.Lamp.Color='green';
               SetDeviceStatus(app,'Device Connected');
               app.ParticipantUIDPMEditField.Enable=true;
               app.CombinedButton.Enable=true;
               app.StaticButton.Enable=true;
               app.BreathButton.Enable=true;
            end
        end
        
        function SetDeviceStatus(app,statusString)
             app.WaitingforDevicetoConnectLabel.Text=statusString;
        end
        
        function SetHelpStatus(app,statusString1)
            app.HelpLabel.Text=statusString1;
        end
        
        function RunDataCollection(app)
            DetermineConfig(app)
            ScheduleNextTrial(app)
            ConfigureDevice(app)
            RunDevice(app)
        end

        function ContinueDataCollection(app)
            ScheduleNextTrial(app)
            ConfigureDevice(app)
            RunDevice(app)
        end
        function WriteData2File(app,rcvData,typer)
            if typer==1
                fileID=fopen(strcat(app.curFolderPath,"\",app.curLogFileName),'w');
                fwrite(fileID,rcvData);
                fclose(fileID);
            elseif  typer==2            
                fileID=fopen(strcat(app.curFolderPath,"\",app.curDataFileName),'w');
                fwrite(fileID,rcvData);
                fclose(fileID);
            end
        end


        function ClinicalConfig(app)
            [app.T1_Time,app.T2_Time,app.T3_Time,app.T4_Time]=ReadTimes();
            if ~app.trialRequestFlag
                    if app.Mode=="Breath"
                        app.RunSequence{1}={"Breath",1,num2str(app.T1_Time)};
                        app.RunSequence{2}={"Breath",2,num2str(app.T2_Time)};
                        app.RunSequence{3}={"Breath",3,num2str(app.T3_Time)};
                   
                    elseif app.Mode=="Static"
                       app.RunSequence{4}={"Static",1,num2str(app.T4_Time)};
                    elseif app.Mode=="Combined"

                        app.RunSequence{1}={"Breath",1,num2str(app.T1_Time)};
                        app.RunSequence{2}={"Breath",2,num2str(app.T2_Time)};
                        app.RunSequence{3}={"Breath",3,num2str(app.T3_Time)};
                        app.RunSequence{4}={"Static",1,num2str(app.T4_Time)};

                    end
                else
                    if app.Mode=="Breath"
                        expDuration=num2str(app.T1_Time);
                    elseif app.Mode=="Static"
                        expDuration=num2str(app.T4_Time);
                    end
                    app.RunSequence{1}={app.Mode,app.Trialno,expDuration};
            end
        end

        function LabConfig(app)
           if ~app.trialRequestFlag1
                if app.Mode=="BreathEmulate"
                    expDuration='160';
                    runMode="BreathEmulate";
                    trls=1;
                    app.RunSequence{trls}={runMode,trls,expDuration};
                elseif app.Mode=="Static"
                    expDuration='180';
                    runMode="Static";
                    trls=1;
                    app.RunSequence{trls}={runMode,trls,expDuration};
                elseif app.Mode=="Both"
                       expDuration='160';
                    runMode="BreathEmulate";
                    trls=1;
                    app.RunSequence{trls}={runMode,trls,expDuration};
                    expDuration='180';
                    runMode="Static";
                    trls=1;
                    app.RunSequence{1+trls}={runMode,trls,expDuration};
                end
            else
                if app.Mode=="Breath"
                    expDuration='160';
                elseif app.Mode=="Static"
                    expDuration="180";
                end
                app.RunSequence{1}={app.Mode,app.Trialno,expDuration};
            end
        end


          function DetermineConfig(app)
            clear app.RunSequence
            if app.analyType=="Clinical"
                app.ClinicalConfig()
            elseif app.analyType=="Lab"
                app.LabConfig()
            end
        end

        function ScheduleNextTrial(app)
            app.trlPtr=app.trlPtr+1;
            app.CompleteFlag=false;
            if app.trlPtr>=length(app.RunSequence)
                app.CompleteFlag=true;
            else
                app.CompleteFlag=false;
            end
        end
        
        function RunDevice(app)
            crntTrial=app.RunSequence{app.trlPtr};
            app.Lamp.Color='yellow';
            SetDeviceStatus(app,'Recording Data');
            SetHelpStatus(app,"Recording Data"+newline+"Please Wait!")
            app.mOde=crntTrial{1};
            trialNo=crntTrial{2};
            expDuration=crntTrial{3};
            app.CurEndTime=expDuration;
            app.curDataFileName=strcat("Data_",app.UID,"_",app.mOde,"_T",num2str(trialNo),".csv");
            app.curLogFileName=strcat("Log_",app.UID,"_",app.mOde,"_T",num2str(trialNo),".txt");
            app.SaveFileNameLabel.Text=app.curDataFileName;
            if app.trlPtr+1<=length(app.RunSequence)
                nxtTrial=app.RunSequence{app.trlPtr+1};
                mOdeNext=nxtTrial{1};
                nextTrial=nxtTrial{2};
            else
                mOdeNext="None";
                nextTrial="None";
            end
            app.CurrentMode.Text=app.mOde;
            app.CurrentTrial.Text=num2str(trialNo);
            app.NextMode.Text=mOdeNext;
            app.NextTrial.Text=num2str(nextTrial);
            app.currentTrialNo=trialNo;
            
            app.SetDurationTimer.Text=char(duration(0,0,str2num(expDuration)));
            ComsDataWrite(app,'Start')
            
                timerStart=0;
                while true
                    pause(1); 
                    timerStart=timerStart+1;
                    app.Label_3.Text=num2str(char(duration(0,0,timerStart)));
                    if app.stopFlag || timerStart>=str2num(expDuration)
                        timerStart=0;
                        app.Label_3.Text=num2str(char(duration(0,0,timerStart)));
                        app.SetDurationTimer.Text=num2str(char(duration(0,0,timerStart)));
                        break;
                    end
                end
                while true
                    pause(3)
                    rcvdData=ComsDataRead(app);
                    if rcvdData=="Recording Complete!"
                        DisplayComsData(app,rcvdData);
                            if ~app.stopFlag
                                app.ComsDataWrite("Send Last File")
                                pause(0.2)
                                rcvdData=ComsDataRead(app);
                                pause(0.5)
                                app.Lamp.Color='red';
                                SetDeviceStatus(app,'Busy!');
                                SetHelpStatus(app,"Transfering Data.."+newline+"Please Wait")
                                if(rcvdData=="Ready")
                                    app.DisplayComsData("Sending Last File");
                                    pause(5);
                                    dataRcvd=app.ComsDataRead();
                                    WriteData2File(app,dataRcvd,1);
                                    pause(10);
                                    dataRcvd=app.ComsDataRead();
                                    WriteData2File(app,dataRcvd,2);
                                    app.DisplayComsData("Data Sent!");
                                    app.PlotGraphs();
                                end
                                if app.CompleteFlag
                                    app.StopButton.Enable=false;
                                    app.StartButton.Enable=true;
                                    app.ContinueButton.Enable=false;
                                    app.Lamp.Color='green';
                                    SetDeviceStatus(app,'Ready!');
                                   SetHelpStatus(app,strcat("End of Data Collection"))
                                else
                                    app.StopButton.Enable=true;
                                    app.StartButton.Enable=false;
                                    app.ContinueButton.Enable=true;
                                    app.Lamp.Color='green';
                                    SetDeviceStatus(app,'Ready!');
                                    SetHelpStatus(app,"Trial Complete"+newline+"Press Continue")
                                    flush(app.Client)
                                end
                            end
                        app.DeviceSessionFlag=false;
                        break;
                    end
                end
        end

        function ConfigureDevice(app)
            ManageFilePaths(app);
            crntTrial=app.RunSequence{app.trlPtr};
            mOde=crntTrial{1};
            trialNo=crntTrial{2};
            expDuration=crntTrial{3};
            app.Lamp.Color='yellow';
            SetDeviceStatus(app,'Configuring');
            SetHelpStatus(app,"Configuring"+newline+"Please Wait!")
            ComsDataWrite(app,"Configuration");
            pause(0.5); rcvdData=ComsDataRead(app);
            disp(rcvdData)
            app.DeviceSessionFlag=true;
            if strcmp(rcvdData,"Ready")
                ComsDataWrite(app,app.UID)
                pause(0.2); rcvdData=ComsDataRead(app);
                
                if strcmp(rcvdData,"Ok")
                    ComsDataWrite(app,num2str(trialNo))
                    pause(0.2); rcvdData=ComsDataRead(app);
                    if strcmp(rcvdData,"Ok")
                        ComsDataWrite(app,mOde)
                        pause(0.4); rcvdData=ComsDataRead(app);
                        if strcmp(rcvdData,"Ok")
                            ComsDataWrite(app,expDuration)
                            pause(0.4); rcvdData=ComsDataRead(app);
                            if strcmp(rcvdData,"Ok")
                                ComsDataWrite(app,app.SamplingRate)
                                pause(0.2); rcvdData=ComsDataRead(app);
                                if strcmp(rcvdData,"Ok")
                                    ComsDataWrite(app,num2str(app.SamplingBuffer))
                                    pause(0.2); rcvdData=ComsDataRead(app);
                                    if strcmp(rcvdData,"Ok")
                                        ComsDataWrite(app,num2str(app.UserName))
                                        pause(0.3); rcvdData=ComsDataRead(app);
                                        disp(rcvdData)
                                        pause(0.7); rcvdData=ComsDataRead(app);
                                        disp(rcvdData)
                                        DisplayComsData(app,rcvdData);
                                        app.Lamp.Color='green';
                                        SetDeviceStatus(app,'Configured! Ready to Start');
                                        SetHelpStatus(app,"Device is configured and capturing data now.")
                                    end
                                end
                            end
                        end
                    end
                end
            end
            
        end
        
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Mode='Breath';
            app.UserName="Sanjay Sarma O V";
            app.Trialno=1;
            app.stopFlag=false;
            app.trialRequestFlag=false;
            app.trialRequestFlag1=false;
            app.ContinueFlag=false;
            app.trlPtr=0;
            app.CompleteFlag=false;
            app.DeviceSessionFlag=false;
            app.currentPath=pwd;
            app.SaveLabel.Text=pwd;
            app.analyType="Clinical";
              app.FID_File=fopen(strcat(pwd,'\IPConfigFile.txt'));
            app.FID_Text=textscan(app.FID_File,'%s');
            app.IP_Addr=string(app.FID_Text{1}{1});
            app.Port_Addr=str2num(string(app.FID_Text{1}{2}));

            fclose(app.FID_File);
            
            SetHelpStatus(app,"Please Wait for the device"+newline+" to connect!")
            WaitForDeviceConnection(app)
            SetHelpStatus(app,"Device Connected"+newline+"Enter Data and"+newline+"Press 'Start'")
            app.StartButton.Enable=true;


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                app.StopButton.Enable=true;
                app.StartButton.Enable=false;
                app.ContinueButton.Enable=false;
                app.trlPtr=0;
                app.RunSequence={};
                app.RunDataCollection();

        end

        % Value changed function: ParticipantUIDPMEditField
        function ParticipantUIDPMEditFieldValueChanged(app, event)
            value = app.ParticipantUIDPMEditField.Value;
            app.UID=value;
        end

        % Selection changed function: ModeSector
        function ModeSectorSelectionChanged(app, event)
            selectedButton = app.ModeSector.SelectedObject;
            app.Mode=selectedButton.Text;
            switch selectedButton.Text
                case 'Breath'
                    disp('Breath')
                case 'Static'
                    disp('Static')
                case 'Combined'
                    disp('Combined')
            end
            
        end

        % Value changed function: TrialNo
        function TrialNoValueChanged(app, event)
            value = app.TrialNo.Value;
            app.Trialno=value;
        end

        % Value changed function: UserDropDown
        function UserDropDownValueChanged(app, event)
            value = app.UserDropDown.Value;
            app.UserName=value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.DeviceSessionFlag
                ComsDataWrite(app,'Stop')
                app.DeviceSessionFlag=false;
            end
            pause(0.2);
            app.StopButton.Enable=false;
            app.StartButton.Enable=true;
            app.ContinueButton.Enable=false;
            app.stopFlag=true;
            SetHelpStatus(app,"Recording Stopped!"+newline+"Enter Data and"+newline+"Press 'Start'")
            app.Lamp.Color='green';
            SetDeviceStatus(app,'Ready!');
            app.stopFlag=false;
        end

        % Value changed function: trialRequestToggle
        function trialRequestToggleValueChanged(app, event)
            value = app.trialRequestToggle.Value;
            if value==true
                app.trialRequestFlag=true;
                app.TrialNo.Enable=true;
            else
                app.trialRequestFlag=false;
                app.TrialNo.Enable=false;
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.StopButton.Enable=true;
            app.StartButton.Enable=false;
            app.ContinueButton.Enable=false;
            ContinueDataCollection(app)

        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            newPath=uigetdir();
            if newPath~=0
                app.currentPath=newPath;
            end
            app.SaveLabel.Text=app.currentPath;
        end

        % Selection changed function: ModeSector_2
        function ModeSector_2SelectionChanged(app, event)
            selectedButton = app.ModeSector_2.SelectedObject;
            app.Mode=selectedButton.Text;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            AnalysisTpe=selectedTab.Title;
            app.analyType=AnalysisTpe;
            h1=app.DataGroup.Children(1);
            h2=app.DataGroup.Children(2);
            h3=app.DataGroup.Children(3);
            switch AnalysisTpe
                case 'Clinical'
                    app.DataGroup.SelectedTab=h1;
                case 'Lab'
                    app.DataGroup.SelectedTab=h2;
                case 'Ventilator'
                    app.DataGroup.SelectedTab=h3;
            end
                    

        end

        % Value changed function: BacType
        function BacTypeValueChanged(app, event)
            value = app.BacType.Value;
             app.UID=value;
        end

        % Value changed function: trialRequestToggle1
        function trialRequestToggle1ValueChanged(app, event)
            value = app.trialRequestToggle1.Value;
                       
            if value==true
                app.trialRequestFlag1=true;
                app.TrialNo_2.Enable=true;
            else
                app.trialRequestFlag=false;
                app.TrialNo_2.Enable=false;
            end
        end

        % Menu selected function: ConsentForm
        function ConsentFormMenuSelected(app, event)
            web(['https://ugeorgia.ca1.qualtrics.com/jfe/form/SV_0JldGJjipETDE8e']);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            About;
        end

        % Menu selected function: SurveyForm
        function SurveyFormMenuSelected(app, event)
            web(['https://ugeorgia.ca1.qualtrics.com/jfe/form/SV_bOuZgVoIck3yQzI']);
        end

        % Menu selected function: Settings
        function SettingsMenuSelected(app, event)
            SettingsApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PRANASPortal = uifigure('Visible', 'off');
            app.PRANASPortal.Name = 'PRANAS-Portal';
            app.PRANASPortal.Icon = 'Pranas_Logo.png';
            app.Settings = uimenu(app.PRANASPortal);
            app.Settings.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.Settings.Text = 'Settings';
            app.Forms = uimenu(app.PRANASPortal);
            app.Forms.Text = 'Forms';
            app.ConsentForm = uimenu(app.Forms);
            app.ConsentForm.MenuSelectedFcn = createCallbackFcn(app, @ConsentFormMenuSelected, true);
            app.ConsentForm.Text = 'Consent';
            app.SurveyForm = uimenu(app.Forms);
            app.SurveyForm.MenuSelectedFcn = createCallbackFcn(app, @SurveyFormMenuSelected, true);
            app.SurveyForm.Text = 'Survey';
            app.AboutMenu = uimenu(app.PRANASPortal);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DataGroup = uitabgroup(app.PRANASPortal);
            app.RATab = uitab(app.DataGroup);
            app.PowerTimeSeriesPlot = uiaxes(app.RATab);
            xlabel(app.PowerTimeSeriesPlot, 'Time (s)')
            ylabel(app.PowerTimeSeriesPlot, 'Pattern')
            zlabel(app.PowerTimeSeriesPlot, 'Z')
            app.Panel_8 = uipanel(app.RATab);
            app.RespiratoryQualityIndexLabel = uilabel(app.Panel_8);
            app.RespiratoryQualityIndexLabel.Text = 'Respiratory Quality Index';
            app.RR_Value = uilabel(app.Panel_8);
            app.RR_Value.Text = '0';
            app.LCLabel = uilabel(app.Panel_8);
            app.LCLabel.Text = 'LC';
            app.LC_Value = uilabel(app.Panel_8);
            app.LC_Value.Text = '0';
            app.QLabel = uilabel(app.Panel_8);
            app.QLabel.Text = 'Q';
            app.Q_Value = uilabel(app.Panel_8);
            app.Q_Value.Text = '0';
            app.RRLabel_2 = uilabel(app.Panel_8);
            app.RRLabel_2.Text = 'RR';
            app.RespiratoryAnalysisLabel = uilabel(app.Panel_8);
            app.RespiratoryAnalysisLabel.Text = 'Respiratory Analysis';
            app.QI3 = uilamp(app.Panel_8);
            app.QI3.Enable = 'off';
            app.QI2 = uilamp(app.Panel_8);
            app.QI2.Enable = 'off';
            app.QI1 = uilamp(app.Panel_8);
            app.QI1.Enable = 'off';
            app.AGTab = uitab(app.DataGroup);
            app.XTimeSeriesPlot = uiaxes(app.AGTab);
            xlabel(app.XTimeSeriesPlot, 'Time (s)')
            ylabel(app.XTimeSeriesPlot, 'X')
            zlabel(app.XTimeSeriesPlot, 'Z')
            app.YTimeSeriesPlot = uiaxes(app.AGTab);
            xlabel(app.YTimeSeriesPlot, 'Time (s)')
            ylabel(app.YTimeSeriesPlot, 'Y')
            zlabel(app.YTimeSeriesPlot, 'Z')
            app.Power1TimeSeriesPlot = uiaxes(app.AGTab);
            xlabel(app.Power1TimeSeriesPlot, 'Time (s)')
            ylabel(app.Power1TimeSeriesPlot, 'Power')
            zlabel(app.Power1TimeSeriesPlot, 'Z')
            app.Tab = uitab(app.DataGroup);
            app.Panel = uipanel(app.PRANASPortal);
            app.Image = uiimage(app.PRANASPortal);
            app.Image.ImageSource = 'Pranas_Logo.png';
            app.Panel_2 = uipanel(app.PRANASPortal);
            app.Lamp = uilamp(app.Panel_2);
            app.WaitingforDevicetoConnectLabel = uilabel(app.Panel_2);
            app.WaitingforDevicetoConnectLabel.Text = 'Waiting for Device to Connect';
            app.StartButton = uibutton(app.PRANASPortal, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.PRANASPortal, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.DeviceResponse = uitextarea(app.PRANASPortal);
            app.DeviceResponse.Value = {'Device Response'};
            app.Panel_3 = uipanel(app.PRANASPortal);
            app.Image2 = uiimage(app.Panel_3);
            app.Image2.ImageSource = 'dicelogo.jpg';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = {'Developed at Design Informatics and Computational Engineering Lab, University of Georgia, Athens, GA, USA - 30602'; ''};
            app.DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_2 = uilabel(app.Panel_3);
            app.DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_2.Text = 'Sanjay Sarma O V, Ramana M Pidaparti, VSSN Rao Tatavarti';
            app.DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_3 = uilabel(app.Panel_3);
            app.DeviceandSoftwaredevelopedbySanjaySarmaOVLabel_3.Text = 'Inventors';
            app.Image4 = uiimage(app.Panel_3);
            app.Image4.ImageSource = 'GLogo.png';
            app.Panel_5 = uipanel(app.PRANASPortal);
            app.HelpLabel = uilabel(app.Panel_5);
            app.HelpLabel.Text = {'Switch on the device'; 'and wait for it to connect.'};
            app.ParticipantUIDEditFieldLabel_4 = uilabel(app.PRANASPortal);
            app.ParticipantUIDEditFieldLabel_4.Text = {'Save'; 'Directory'};
            app.SaveLabel = uilabel(app.PRANASPortal);
            app.SaveLabel.Text = 'Save Directory';
            app.Image3 = uiimage(app.PRANASPortal);
            app.Image3.ImageSource = 'DevicePIc.png';
            app.ChangeButton = uibutton(app.PRANASPortal, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.ParticipantUIDEditFieldLabel_6 = uilabel(app.PRANASPortal);
            app.ParticipantUIDEditFieldLabel_6.Text = 'Saved File Name';
            app.SaveFileNameLabel = uilabel(app.PRANASPortal);
            app.SaveFileNameLabel.Text = 'Saved File Name';
            app.UserDropDownLabel = uilabel(app.PRANASPortal);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.PRANASPortal);
            app.UserDropDown.Items = {'Sanjay Sarma O V', 'Ramana M Pidaparti', 'Rebecca Hayes', 'Dr. Nikhil Madala', 'Dr. Rafael Da Silva'};
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @UserDropDownValueChanged, true);
            app.UserDropDown.Value = 'Sanjay Sarma O V';
            app.ContinueButton = uibutton(app.PRANASPortal, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.Panel_6 = uipanel(app.PRANASPortal);
            app.CurrentDurationLabel = uilabel(app.Panel_6);
            app.CurrentDurationLabel.Text = 'Current Duration:';
            app.SetDurationLabel = uilabel(app.Panel_6);
            app.SetDurationLabel.Text = 'Set Duration:';
            app.SetDurationTimer = uilabel(app.Panel_6);
            app.SetDurationTimer.Text = '00:00:00';
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = '00:00:00';
            app.Panel_7 = uipanel(app.PRANASPortal);
            app.CurrentLabel = uilabel(app.Panel_7);
            app.CurrentLabel.Text = 'Current:';
            app.TrialLabel = uilabel(app.Panel_7);
            app.TrialLabel.Text = 'Trial';
            app.CurrentMode = uilabel(app.Panel_7);
            app.CurrentMode.Text = 'None';
            app.NextLabel = uilabel(app.Panel_7);
            app.NextLabel.Text = 'Next:';
            app.ModeLabel = uilabel(app.Panel_7);
            app.ModeLabel.Text = 'Mode';
            app.NextMode = uilabel(app.Panel_7);
            app.NextMode.Text = 'None';
            app.CurrentTrial = uilabel(app.Panel_7);
            app.CurrentTrial.Text = 'None';
            app.NextTrial = uilabel(app.Panel_7);
            app.NextTrial.Text = 'None';
            app.Image5 = uiimage(app.PRANASPortal);
            app.Image5.ImageSource = 'PRANAS-Title.png';
            app.TabGroup = uitabgroup(app.PRANASPortal);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AnalysisType = uitab(app.TabGroup);
            app.AnalysisType.Title = 'Clinical';
            app.ConfigurationLabel = uilabel(app.AnalysisType);
            app.ConfigurationLabel.Text = 'Configuration';
            app.ParticipantUIDPMLabel = uilabel(app.AnalysisType);
            app.ParticipantUIDPMLabel.Text = 'Participant UID: PM-';
            app.ParticipantUIDPMEditField = uieditfield(app.AnalysisType, 'text');
            app.ParticipantUIDPMEditField.ValueChangedFcn = createCallbackFcn(app, @ParticipantUIDPMEditFieldValueChanged, true);
            app.ParticipantUIDPMEditField.Enable = 'off';
            app.ParticipantUIDEditFieldLabel_2 = uilabel(app.AnalysisType);
            app.ParticipantUIDEditFieldLabel_2.Text = 'Analysis Type';
            app.ModeSector = uibuttongroup(app.AnalysisType);
            app.ModeSector.SelectionChangedFcn = createCallbackFcn(app, @ModeSectorSelectionChanged, true);
            app.BreathButton = uiradiobutton(app.ModeSector);
            app.BreathButton.Enable = 'off';
            app.BreathButton.Text = 'Breath';
            app.BreathButton.Value = true;
            app.StaticButton = uiradiobutton(app.ModeSector);
            app.StaticButton.Enable = 'off';
            app.StaticButton.Text = 'Static';
            app.CombinedButton = uiradiobutton(app.ModeSector);
            app.CombinedButton.Enable = 'off';
            app.CombinedButton.Text = 'Combined';
            app.ParticipantUIDEditFieldLabel_3 = uilabel(app.AnalysisType);
            app.ParticipantUIDEditFieldLabel_3.Text = 'Trial #';
            app.TrialNo = uispinner(app.AnalysisType);
            app.TrialNo.Limits = [1 Inf];
            app.TrialNo.ValueChangedFcn = createCallbackFcn(app, @TrialNoValueChanged, true);
            app.TrialNo.Enable = 'off';
            app.TrialNo.Value = 1;
            app.trialRequestToggle = uicheckbox(app.AnalysisType);
            app.trialRequestToggle.ValueChangedFcn = createCallbackFcn(app, @trialRequestToggleValueChanged, true);
            app.trialRequestToggle.Text = '';
            app.LabTab = uitab(app.TabGroup);
            app.LabTab.Title = 'Lab';
            app.ConfigurationLabel_2 = uilabel(app.LabTab);
            app.ConfigurationLabel_2.Text = 'Configuration';
            app.BacteriaTypeBEditFieldLabel = uilabel(app.LabTab);
            app.BacteriaTypeBEditFieldLabel.Text = 'Bacteria Type: B-';
            app.BacType = uieditfield(app.LabTab, 'text');
            app.BacType.ValueChangedFcn = createCallbackFcn(app, @BacTypeValueChanged, true);
            app.ParticipantUIDEditFieldLabel_7 = uilabel(app.LabTab);
            app.ParticipantUIDEditFieldLabel_7.Text = 'Analysis Type';
            app.ModeSector_2 = uibuttongroup(app.LabTab);
            app.ModeSector_2.SelectionChangedFcn = createCallbackFcn(app, @ModeSector_2SelectionChanged, true);
            app.BreathEmulateButton = uiradiobutton(app.ModeSector_2);
            app.BreathEmulateButton.Text = 'BreathEmulate';
            app.BreathEmulateButton.Value = true;
            app.StaticButton_2 = uiradiobutton(app.ModeSector_2);
            app.StaticButton_2.Text = 'Static';
            app.BothButton = uiradiobutton(app.ModeSector_2);
            app.BothButton.Text = 'Both';
            app.ParticipantUIDEditFieldLabel_8 = uilabel(app.LabTab);
            app.ParticipantUIDEditFieldLabel_8.Text = 'Trial #';
            app.TrialNo_2 = uispinner(app.LabTab);
            app.TrialNo_2.Limits = [1 Inf];
            app.TrialNo_2.Enable = 'off';
            app.TrialNo_2.Value = 1;
            app.trialRequestToggle1 = uicheckbox(app.LabTab);
            app.trialRequestToggle1.ValueChangedFcn = createCallbackFcn(app, @trialRequestToggle1ValueChanged, true);
            app.trialRequestToggle1.Text = '';
            app.VentilatorTab = uitab(app.TabGroup);
            app.VentilatorTab.Title = 'Ventilator';
            % Show the figure after all components are created
            app.PRANASPortal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RD3_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PRANASPortal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PRANASPortal)
        end
    end
end