classdef SettingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TrialTimesPanel          matlab.ui.container.Panel
        StaticT1EditField        matlab.ui.control.NumericEditField
        StaticT1EditFieldLabel   matlab.ui.control.Label
        BreathT3EditField        matlab.ui.control.NumericEditField
        BreathT3EditFieldLabel   matlab.ui.control.Label
        BreathT2EditField        matlab.ui.control.NumericEditField
        BreathT2EditFieldLabel   matlab.ui.control.Label
        BreathT1EditField        matlab.ui.control.NumericEditField
        BreathT1EditFieldLabel   matlab.ui.control.Label
        ConnectionSettingsPanel  matlab.ui.container.Panel
        PortEditField            matlab.ui.control.NumericEditField
        PortEditFieldLabel       matlab.ui.control.Label
        PCIPEditField            matlab.ui.control.EditField
        PCIPEditFieldLabel       matlab.ui.control.Label
        DefaultButton            matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        FID_File % Stored IP
        FID_Text
        IP_Addr
        Port_Addr
        T1_Time
        T2_Time
        T3_Time
        T4_Time
    end
    
    methods (Access = private)
        
        function ReadValues(app)
            app.FID_File=fopen(strcat(pwd,'\IPConfigFile.txt'));
            app.FID_Text=textscan(app.FID_File,'%s');
            app.IP_Addr=string(app.FID_Text{1}{1});
            app.Port_Addr=str2num(string(app.FID_Text{1}{2}));
            app.T1_Time=str2num(string(app.FID_Text{1}{3}));
            app.T2_Time=str2num(string(app.FID_Text{1}{4}));
            app.T3_Time=str2num(string(app.FID_Text{1}{5}));
            app.T4_Time=str2num(string(app.FID_Text{1}{6}));
            fclose(app.FID_File);
        end
        function ShowValues(app)
        
            app.PCIPEditField.Value=app.IP_Addr;
            app.PortEditField.Value=app.Port_Addr;
            app.BreathT1EditField.Value=app.T1_Time;
            app.BreathT2EditField.Value=app.T2_Time;
            app.BreathT3EditField.Value=app.T3_Time;
            app.StaticT1EditField.Value=app.T4_Time;
        
        
        end

        
        function CurrentValues(app)
            ReadValues(app);
            ShowValues(app);
%             app.IP_Addr="192.168.137.1";
%             app.Port_Addr=9001;
%             app.PCIPEditField.Value=app.IP_Addr;
%             app.PortEditField.Value=app.Port_Addr;

        end
        
        function WriteValues(app)
            app.FID_File=fopen(strcat(pwd,'\IPConfigFile.txt'),"w");
            fprintf(app.FID_File,'%s\n',app.IP_Addr);
            fprintf(app.FID_File,'%d\n',app.Port_Addr);
            fprintf(app.FID_File,'%d\n',app.T1_Time);
            fprintf(app.FID_File,'%d\n',app.T2_Time);
            fprintf(app.FID_File,'%d\n',app.T3_Time);
            fprintf(app.FID_File,'%d\n',app.T4_Time);
            fclose(app.FID_File);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ReadValues()
            app.ShowValues()
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            CurrentValues(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.WriteValues();
        end

        % Value changed function: PCIPEditField
        function PCIPEditFieldValueChanged(app, event)
            app.IP_Addr = app.PCIPEditField.Value;
            
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.Port_Addr = app.PortEditField.Value;
            
        end

        % Value changed function: StaticT1EditField
        function StaticT1EditFieldValueChanged(app, event)
            app.T4_Time = app.StaticT1EditField.Value;
            
        end

        % Value changed function: BreathT1EditField
        function BreathT1EditFieldValueChanged(app, event)
            app.T1_Time = app.BreathT1EditField.Value;
            
        end

        % Value changed function: BreathT2EditField
        function BreathT2EditFieldValueChanged(app, event)
            app.T2_Time = app.BreathT2EditField.Value;
            
        end

        % Value changed function: BreathT3EditField
        function BreathT3EditFieldValueChanged(app, event)
            app.T3_Time = app.BreathT3EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ConnectionSettingsPanel = uipanel(app.UIFigure);
            app.ConnectionSettingsPanel.Title = 'Connection Settings';
            app.PCIPEditFieldLabel = uilabel(app.ConnectionSettingsPanel);
            app.PCIPEditFieldLabel.Text = 'PC IP';
            app.PCIPEditField = uieditfield(app.ConnectionSettingsPanel, 'text');
            app.PCIPEditField.ValueChangedFcn = createCallbackFcn(app, @PCIPEditFieldValueChanged, true);
            app.PortEditFieldLabel = uilabel(app.ConnectionSettingsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConnectionSettingsPanel, 'numeric');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.TrialTimesPanel = uipanel(app.UIFigure);
            app.TrialTimesPanel.Title = 'Trial Times';
            app.BreathT1EditFieldLabel = uilabel(app.TrialTimesPanel);
            app.BreathT1EditFieldLabel.Text = 'Breath T1';
            app.BreathT1EditField = uieditfield(app.TrialTimesPanel, 'numeric');
            app.BreathT1EditField.ValueChangedFcn = createCallbackFcn(app, @BreathT1EditFieldValueChanged, true);
            app.BreathT2EditFieldLabel = uilabel(app.TrialTimesPanel);
            app.BreathT2EditFieldLabel.Text = 'Breath T2';
            app.BreathT2EditField = uieditfield(app.TrialTimesPanel, 'numeric');
            app.BreathT2EditField.ValueChangedFcn = createCallbackFcn(app, @BreathT2EditFieldValueChanged, true);
            app.BreathT3EditFieldLabel = uilabel(app.TrialTimesPanel);
            app.BreathT3EditFieldLabel.Text = 'Breath T3';
            app.BreathT3EditField = uieditfield(app.TrialTimesPanel, 'numeric');
            app.BreathT3EditField.ValueChangedFcn = createCallbackFcn(app, @BreathT3EditFieldValueChanged, true);
            app.StaticT1EditFieldLabel = uilabel(app.TrialTimesPanel);
            app.StaticT1EditFieldLabel.Text = 'Static T1';
            app.StaticT1EditField = uieditfield(app.TrialTimesPanel, 'numeric');
            app.StaticT1EditField.ValueChangedFcn = createCallbackFcn(app, @StaticT1EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SettingsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end